classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Panel                 matlab.ui.container.Panel
        BuildgraphButton      matlab.ui.control.Button
        BrowseforimageButton  matlab.ui.control.Button
        CreateedgesButton     matlab.ui.control.Button
        ChooseimageDropDown   matlab.ui.control.DropDown
        LevelDropDown         matlab.ui.control.DropDown
        LevelLabel            matlab.ui.control.Label
        MessageTextAreaLabel  matlab.ui.control.Label
        messageviewTextArea   matlab.ui.control.TextArea
        UpdatedetailButton    matlab.ui.control.Button
        ReconstructButton     matlab.ui.control.Button
        EvaluateButton        matlab.ui.control.Button
        ThresholdEditField    matlab.ui.control.NumericEditField
        ThresholdLabel        matlab.ui.control.Label
        UpdateButton          matlab.ui.control.Button
        CONTROLLabel          matlab.ui.control.Label
        ChooseimageLabel      matlab.ui.control.Label
        Image_2               matlab.ui.control.UIAxes
        Image                 matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        originalImage       % Lưu ảnh gốc (ma trận double)
        maxLevel            % Mức phân giải tối đa có thể
        LL                  % Lưu ma trận xấp xỉ LL ở level cuối cùng
        detailArr           % Cell array lưu các ma trận HL, LH, HH ở từng level
        detailArrAfter      % Cell array lưu các chi tiết sau khi cắt ngưỡng
        reconstructedImage  % Ảnh sau khi tái tạo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ChooseimageDropDown
        function ChooseimageDropDownValueChanged(app, event)
            value = app.ChooseimageDropDown.Value;
            try     
                img = imread(value);
                if size(img, 3) == 3
                    img = rgb2gray(img); 
                end

                [r, c] = size(img);
                app.maxLevel = min(6, floor(log2(min(r, c))));
                
                rNew = r - mod(r, 2^app.maxLevel);
                cNew = c - mod(c, 2^app.maxLevel);

                imageNew = img(1:rNew, 1:cNew);

                app.originalImage = double(imageNew);
                app.LevelDropDown.Items = string(1:app.maxLevel);
                app.messageviewTextArea.Value = {'Image uploaded, choose level and update.'};

                imshow(uint8(app.originalImage), 'Parent', app.Image);
                imshow(uint8(app.originalImage), 'Parent', app.Image_2);

            catch ME
                app.messageviewTextArea.Value = {['Image error: ', ME.message]};
            end
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            try
                level = str2double(app.LevelDropDown.Value);

                [app.LL, app.detailArr] = haar_decompose(app.originalImage, level);

                curImage = app.LL; 

                for i = level:-1:1
                    HL = app.detailArr{i, 1};
                    LH = app.detailArr{i, 2};
                    HH = app.detailArr{i, 3};
                    
                    topHalf = [curImage, HL];
                    bottomHalf = [LH, HH];
                    curImage = [topHalf; bottomHalf];
                end

                imshow(uint8(curImage), 'Parent', app.Image_2);

                app.messageviewTextArea.Value = sprintf('Updated with level = %d', level);

            catch ME
                app.messageviewTextArea.Value = {['Update error: ', ME.message]};
            end
        end

        % Button pushed function: UpdatedetailButton
        function UpdatedetailButtonPushed(app, event)
            try
                tau = app.ThresholdEditField.Value;

                app.detailArrAfter = app.detailArr;

                level = str2double(app.LevelDropDown.Value);

                for i = 1:level
                    for j = 1:3
                        coEff = app.detailArrAfter{i, j};
                        coEff(abs(coEff) < tau) = 0;
                        app.detailArrAfter{i, j} = coEff;
                    end
                end

                app.messageviewTextArea.Value = sprintf('Updated details with threshold = %d', tau);

            catch ME
                app.messageviewTextArea.Value = {['Update detail error: ', ME.message]};
            end
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            try

                level = str2double(app.LevelDropDown.Value);

                if isempty(app.detailArrAfter)
                    details = app.detailArr;
                else
                    details = app.detailArrAfter;
                end

                imageReconstructed = haar_reconstruct(app.LL, details, level);

                imageReconstructed = max(min(imageReconstructed, 255), 0);


                app.reconstructedImage = imageReconstructed;
                imshow(uint8(app.reconstructedImage), 'Parent', app.Image_2);

                app.messageviewTextArea.Value = {'Image reconstructed'};

            catch ME
                app.messageviewTextArea.Value = {['Reconstruct error: ', ME.message]};
            end
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            try
                if isempty(app.originalImage) || isempty(app.reconstructedImage)
                    app.messageviewTextArea.Value = {'Update and reconstruct image before evaluate.'};
                    return;
                end

                I_goc = app.originalImage;
                I_recon = app.reconstructedImage;

                mse_val = mean((I_goc(:) - I_recon(:)).^2);

                if mse_val == 0
                    psnr_val = Inf; 
                else
                    psnr_val = 10 * log10((255^2) / mse_val);
                end


                if isempty(app.detailArrAfter)
                    details = app.detailArr;
                else
                    details = app.detailArrAfter;
                end

                zero_count = 0;
                total_count = numel(app.LL);
                level = str2double(app.LevelDropDown.Value);
                for i = 1:level
                    for j = 1:3
                        coeff = details{i, j};
                        zero_count = zero_count + sum(coeff(:) == 0);
                        total_count = total_count + numel(coeff);
                    end
                end
                sparsity = (zero_count / total_count) * 100;
                
                non_zero_count = total_count - zero_count;
                if non_zero_count == 0
                    compression_ratio = Inf; 
                else
                    compression_ratio = total_count / non_zero_count;
                end

                report = sprintf(['RESULT (Level %d):\n' ...
                    '- MSE: %.2f\n' ...
                    '- PSNR: %.2f dB\n' ...
                    '- Sparsity: %.2f%% \n' ...
                    '- Compression Ratio: 1:%.2f'], level, mse_val, psnr_val, sparsity, compression_ratio);
                app.messageviewTextArea.Value = {report};

            catch ME
                app.messageviewTextArea.Value = {['Evaluate error: ', ME.message]};
            end
        end

        % Button pushed function: CreateedgesButton
        function CreateedgesButtonPushed(app, event)
            try
                if isempty(app.detailArr)
                    app.messageviewTextArea.Value = {'Upadte image before create edges'};
                    return;
                end

                level = str2double(app.LevelDropDown.Value);

                if isempty(app.detailArrAfter)
                    details = app.detailArr;
                else
                    details = app.detailArrAfter;
                end

                zero_LL = zeros(size(app.LL));

                edgeImage = haar_reconstruct(zero_LL, details, level);

                edgeImage = abs(edgeImage);

                edgeImage = edgeImage * 2; 
                edgeImage = max(min(edgeImage, 255), 0); 

                imshow(uint8(edgeImage), 'Parent', app.Image_2);

                app.messageviewTextArea.Value = {'Edges created'};

            catch ME
                app.messageviewTextArea.Value = {['Create edges error: ', ME.message]};
            end
        end

        % Button pushed function: BrowseforimageButton
        function BrowseforimageButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif', 'Image Files (*.jpg, *.png, *.bmp)'}, 'Chọn một bức ảnh từ máy tính');

            if isequal(file, 0)
                return; 
            end

            try
                fullFileName = fullfile(path, file);

                img = imread(fullFileName);

                if size(img, 3) == 3
                    img = rgb2gray(img); 
                end

                [r, c] = size(img);
                app.maxLevel = min(6, floor(log2(min(r, c))));

                rNew = r - mod(r, 2^app.maxLevel);
                cNew = c - mod(c, 2^app.maxLevel);
                imageNew = img(1:rNew, 1:cNew);

                app.originalImage = double(imageNew);
                app.LevelDropDown.Items = string(1:app.maxLevel);

                app.messageviewTextArea.Value = {sprintf('Image %s uploaded, choose level and update.', file)};
                imshow(uint8(app.originalImage), 'Parent', app.Image);
                imshow(uint8(app.originalImage), 'Parent', app.Image_2);

            catch ME
                app.messageviewTextArea.Value = {['Image error: ', ME.message]};
            end
        end

        % Button pushed function: BuildgraphButton
        function BuildgraphButtonPushed(app, event)
            try
                if isempty(app.originalImage) || isempty(app.detailArr)
                    app.messageviewTextArea.Value = {'Please upload image and update before graphing.'};
                    return;
                end

                drawnow; 
                
                level = str2double(app.LevelDropDown.Value);
                I_goc = app.originalImage;

                tau_array = 0:5:250; 
                psnr_array = zeros(size(tau_array));
                sparsity_array = zeros(size(tau_array));
                
                total_count = numel(app.originalImage); 

                for k = 1:length(tau_array)
                    tau = tau_array(k);
                    tempDetails = app.detailArr; 
                    zero_count = 0;

                    for i = 1:level
                        for j = 1:3
                            coEff = tempDetails{i, j};
                            coEff(abs(coEff) < tau) = 0;
                            
                            zero_count = zero_count + sum(coEff(:) == 0);
                            tempDetails{i, j} = coEff;
                        end
                    end
                    

                    sparsity_array(k) = (zero_count / total_count) * 100;

                    I_recon = haar_reconstruct(app.LL, tempDetails, level);
                    I_recon = max(min(I_recon, 255), 0);
                    
                    mse_val = mean((I_goc(:) - I_recon(:)).^2);
                    if mse_val == 0
                        psnr_array(k) = 100; 
                    else
                        psnr_array(k) = 10 * log10((255^2) / mse_val);
                    end
                end

                
                %left y
                yyaxis left
                plot(tau_array, psnr_array, '-o', 'LineWidth', 2, 'MarkerSize', 6);
                ylabel('PSNR (dB)', 'FontSize', 12, 'FontWeight', 'bold');
                
                %right y
                yyaxis right
                plot(tau_array, sparsity_array, '-s', 'LineWidth', 2, 'MarkerSize', 6);
                ylabel('Sparsity (%)', 'FontSize', 12, 'FontWeight', 'bold');
                
                %x
                xlabel('Threshold', 'FontSize', 12, 'FontWeight', 'bold');
                title(sprintf('Đồ thị đánh giá chất lượng ảnh so với dung lượng nén với level nén %d', level), 'FontSize', 14);
                grid on;
                
                %Legend
                legend({'PSNR', 'Sparsity'}, 'Location', 'best');
                
                app.messageviewTextArea.Value = {'Graph done.'};
                
            catch ME
                app.messageviewTextArea.Value = {['Graph error: ', ME.message]};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiaxes(app.UIFigure);
            title(app.Image, 'Original image')
            app.Image.XTick = [];
            app.Image.YTick = [];
            app.Image_2 = uiaxes(app.UIFigure);
            title(app.Image_2, 'Processed image')
            app.Image_2.XTick = [];
            app.Image_2.XTickLabel = '';
            app.Image_2.YTick = [];
            app.Panel = uipanel(app.UIFigure);
            app.ChooseimageLabel = uilabel(app.Panel);
            app.ChooseimageLabel.Text = 'Choose image: ';
            app.CONTROLLabel = uilabel(app.Panel);
            app.CONTROLLabel.Text = 'CONTROL';
            app.UpdateButton = uibutton(app.Panel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.ThresholdLabel = uilabel(app.Panel);
            app.ThresholdLabel.Text = 'Threshold:';
            app.ThresholdEditField = uieditfield(app.Panel, 'numeric');
            app.EvaluateButton = uibutton(app.Panel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.ReconstructButton = uibutton(app.Panel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.UpdatedetailButton = uibutton(app.Panel, 'push');
            app.UpdatedetailButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatedetailButtonPushed, true);
            app.UpdatedetailButton.Text = 'Update detail';
            app.messageviewTextArea = uitextarea(app.Panel);
            app.MessageTextAreaLabel = uilabel(app.Panel);
            app.MessageTextAreaLabel.Text = 'Message ';
            app.LevelLabel = uilabel(app.Panel);
            app.LevelLabel.Text = 'Level:';
            app.LevelDropDown = uidropdown(app.Panel);
            app.LevelDropDown.Items = {'1', '2', '3', '4'};
            app.LevelDropDown.Value = '1';
            app.ChooseimageDropDown = uidropdown(app.Panel);
            app.ChooseimageDropDown.Items = {'dog', 'cat', 'chicken', 'duck', 'letters', 'Kim Ji Won', 'noisy'};
            app.ChooseimageDropDown.ItemsData = {'dog.jpg', 'cat.jpg', 'chicken.jpg', 'duck.jpg', 'letters.jpg', 'KimJiWon.jpg', 'noisy.jpg'};
            app.ChooseimageDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseimageDropDownValueChanged, true);
            app.ChooseimageDropDown.Value = 'dog.jpg';
            app.CreateedgesButton = uibutton(app.Panel, 'push');
            app.CreateedgesButton.ButtonPushedFcn = createCallbackFcn(app, @CreateedgesButtonPushed, true);
            app.CreateedgesButton.Text = 'Create edges';
            app.BrowseforimageButton = uibutton(app.Panel, 'push');
            app.BrowseforimageButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforimageButtonPushed, true);
            app.BrowseforimageButton.Text = 'Browse for image';
            app.BuildgraphButton = uibutton(app.Panel, 'push');
            app.BuildgraphButton.ButtonPushedFcn = createCallbackFcn(app, @BuildgraphButtonPushed, true);
            app.BuildgraphButton.Text = 'Build graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end